classdef mypemfc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        HelpButton                     matlab.ui.control.Button
        MaxcurrentdensityEditFieldLabel  matlab.ui.control.Label
        MaxcurrentdensityEditField     matlab.ui.control.NumericEditField
        Am2Label                       matlab.ui.control.Label
        MaxpowerdensityEditFieldLabel  matlab.ui.control.Label
        MaxpowerdensityEditField       matlab.ui.control.NumericEditField
        Wm2Label                       matlab.ui.control.Label
        ChangevaluesLabel              matlab.ui.control.Label
        GeneratecurvesButton           matlab.ui.control.Button
        Tableoftheexample211pag199CodefromAppendixBpag443Label  matlab.ui.control.Label
        ValuesLabel                    matlab.ui.control.Label
        PhysicalpropertiesLabel        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        MembranethicknessEditFieldLabel  matlab.ui.control.Label
        MembranethicknessEditField     matlab.ui.control.NumericEditField
        ElectrodethicknessEditFieldLabel  matlab.ui.control.Label
        ElectrodethicknessEditField    matlab.ui.control.NumericEditField
        OperationPressureEditFieldLabel  matlab.ui.control.Label
        OperationPressureEditField     matlab.ui.control.NumericEditField
        atmLabel                       matlab.ui.control.Label
        mLabel                         matlab.ui.control.Label
        mLabel_2                       matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GeneratecurvesButton
        function GeneratecurvesButtonPushed(app, event)
           
            format("shortG");
            T=343;
            P=app.OperationPressureEditField.Value;
            ETh=app.ElectrodethicknessEditField.Value;
            MTh=app.MembranethicknessEditField.Value;
            P_A=P*101325;
            P_C=P*101325;
            t_A=ETh*0.000001;
            t_C=ETh*0.000001;
            t_M=MTh*0.000001;
            P_SAT=0.307*101325;
            X_H_I=0.9;
            X_O_IV=0.19;
            X_W_IV=0.1;
            D_HW=1.49*(10^(-5));  %Effective Diffusivity  of Hydrogen Water
            D_OW=2.95*(10^(-6));  %Effective Diffusivity  of Oxygen Water
            alpha=2.;
            i0_ref=1; %Reference  Exchange Current Density
            Cdrag=2.5;
            MW_N=1.0; %Nafion  Molecular  Weight Which Is in kg/mol
            rhod_N= 1970;  %   Density of Dry   Nafion  Which Is  in  kg/m3
            Erev=1.23;  %Reversible Voltage of the Cell
            iLeak=0.0;
            NoS=100;  %   number  of current density steps
            etta_act=zeros(0,NoS);
            etta_ohmic=zeros(0,NoS);
            etta_conc=zeros(0,NoS);
            X_H_II=zeros(0,NoS);
            X_W_II=zeros(0,NoS);
            X_O_III=zeros(0,NoS);
            X_W_III=zeros(0,NoS);
            V_cell=zeros(0,NoS);
            CD=zeros(0,NoS);
            PowerD=zeros(0,NoS);

            %Constants
            R=8.314;
            F=96485;

            %   Obtaining the  values of remained variables  before calculating  voltage
            %   losses for different  values of current density,  i
            iL=4*F*P_C*D_OW*X_O_IV/(R*T*t_C);
            %  D_WN    is the diffusivity of Water in  Nafion
            D_WN=1.3113*(10^(-10))*exp(2416.*(1/303.-1/T));
            X_W_I=1.0-X_H_I;
            X_N_IV=1.0-X_O_IV-X_W_IV;
            syms x
            gamma=0.0;
            newgamma=0.0;
            k=0.0;
           %progress bar
            wb=waitbar(0,'Generating curves...');
           %progress bar
            
            %   Before the  main step, we  calculate the probable flooding current
            %   density  for  which aWtM becomes unity.  This helps us in the building
            %   of  an equation  for the calculation of gamma,k,  landa,  sigma,
            %   and finally ohmic loss.
            X_W_III=P_SAT/P_C;  %   For the  flooded cathode aW must be at  leat  unity.
            PROD=(X_W_III-X_W_IV)*P_C*D_OW*2*F/(R*T*t_C);  %   PROD=i*(gamma+1)
            eqn0=13.373-11.*(PROD/x-1.)/Cdrag-((0.043+17.18*(P_A*(X_W_I-R*T*...
                (PROD-x)*t_A/(P_A*D_HW*2*F))/P_SAT)-39.85*((P_A*(X_W_I-R*T*...
                (PROD-x)*t_A/(P_A*D_HW*2*F))/P_SAT).^2)+36.*((P_A*(X_W_I-R*T*...
                (PROD-x)*t_A/(P_A*D_HW*2*F))/P_SAT).^3))-11.*(PROD/x-1.)/Cdrag)*...
                exp(x*Cdrag*MW_N*t_M/(22*F*rhod_N*D_WN))==0.0;
            iFlood=vpasolve(eqn0,x);
            iFlood=iFlood.*10;

            %Main  Loop
            for j=1:(NoS+1)
                i=(j-1)*(1-1.e-6)*iL/NoS;
                i=i+iLeak;
                CD(j)=i;
                %   Calculating the activation  loss (only cathode)
                if((i/i0_ref)>1)
                    etta_act(j)=(R*T/(alpha*F))*(log(i/i0_ref)-log(P_C*(X_O_IV-R*T*i*...
                        t_C/(4*F*P_C*D_OW))/101325.));
                else
                    etta_act(j)=0.0;
                end
                %Calculating the  ohmic loss
                if(i<iFlood)
                    eqn1=0.043+17.18*((P_C*(X_W_IV+R*T*i*(x+1.)*t_C/(P_C*D_OW*2*F))...
                        /P_SAT))-39.85*((P_C*(X_W_IV+R*T*i*(x+1.)*t_C/(P_C*D_OW*2*F))...
                        /P_SAT)).^2+36.*((P_C*(X_W_IV+R*T*i*(x+1.)*t_C/(P_C*D_OW*2*F))...
                        /P_SAT)).^3-11.*x/Cdrag-((0.043+17.18*(P_A*(X_W_I-R*T*i*x*...
                        t_A/(P_A*D_HW*2*F))/P_SAT)-39.85*((P_A*(X_W_I-R*T*i*x*t_A/...
                        (P_A*D_HW*2*F))/P_SAT).^2)+36.*((P_A*(X_W_I-R*T*i*x*t_A/...
                        (P_A*D_HW*2*F))/P_SAT).^3))-11.*x/Cdrag)*exp(i*Cdrag*MW_N*...
                        t_M/(22*F*rhod_N*D_WN))==0.0;
                    newgamma1  =  solve(eqn1,x);
                    newgamma1  =  double(newgamma1);
                    newgamma1  =  newgamma1(imag(newgamma1)  ==  0);
                    newgamma1  =  newgamma1(newgamma1>0);
                    if(isempty(newgamma1)~=1  )
                        newgamma   =     newgamma1(1);
                    else
                        %   fprintf("Error:  Root of  gamma  was   not found................  ");
                    end
                    gamma=newgamma;
                    k=((0.043+17.18*(P_A*(X_W_I-R*T*i*gamma*t_A/(P_A*D_HW*2*F))/P_SAT)...
                        -39.85*((P_A*(X_W_I-R*T*i*gamma*t_A/(P_A*D_HW*2*F))/P_SAT).^2)+...
                        36.*((P_A*(X_W_I-R*T*i*gamma*t_A/(P_A*D_HW*2*F))/P_SAT).^3))-...
                        11.*gamma/Cdrag);
                else
                    eqn2=13.373+4.*((P_C*(X_W_IV+R*T*i*(x+1.)*t_C/(P_C*D_OW*2*F))/...
                        P_SAT)-1.)-11.*x/Cdrag-((0.043+17.18*(P_A*(X_W_I-R*T*i*x*t_A/...
                        (P_A*D_HW*2*F))/P_SAT)-39.85*((P_A*(X_W_I-R*T*i*x*t_A/...
                        (P_A*D_HW*2*F))/P_SAT).^2)+36.*((P_A*(X_W_I-R*T*i*x*t_A/...
                        (P_A*D_HW*2*F))/P_SAT).^3))-11.*x/Cdrag)*exp(i*Cdrag*MW_N*...
                        t_M/(22*F*rhod_N*D_WN))==0.0;
                    newgamma2  =  solve(eqn2,x);
                    newgamma2  =  double(newgamma2);
                    newgamma2  =  newgamma2(imag(newgamma2)  ==  0);
                    newgamma2  =  newgamma2(newgamma2>0);
                    if(isempty(newgamma2)~=1  )
                        if((P_A*(X_W_I-R*T*i*newgamma2(1)*t_A/(P_A*D_HW*2*F))/P_SAT)<=1.)
                            newgamma   =     newgamma2(1);
                            gamma=newgamma;
                            k=((0.043+17.18*(P_A*(X_W_I-R*T*i*gamma*t_A/(P_A*D_HW*2*F))...
                                /P_SAT)-39.85*((P_A*(X_W_I-R*T*i*gamma*t_A/...
                                (P_A*D_HW*2*F))/P_SAT).^2)+36.*((P_A*(X_W_I-R*T*i*gamma*...
                                t_A/(P_A*D_HW*2*F))/P_SAT).^3))-11.*gamma/Cdrag);
                        else
                            fprintf("aW0  smaller than unity was   not  found for after ")
                            fprintf("flooding  point region.");
                        end
                    else
                        eqn2=13.373+4.*((P_C*(X_W_IV+R*T*i*(x+1.)*t_C/(P_C*D_OW*2*F))...
                            /P_SAT)-1.)-11.*x/Cdrag-((13.373+4.0*(P_A*(X_W_I-R*T*i*x*...
                            t_A/(P_A*D_HW*2*F))/P_SAT)-1.)-11.*x/Cdrag)*exp(i*Cdrag*...
                            MW_N*t_M/(22*F*rhod_N*D_WN))==0.0;
                        newgamma2  =  solve(eqn2,x);
                        newgamma2  =  double(newgamma2);
                        newgamma2  =  newgamma2(imag(newgamma2)  ==  0);
                        newgamma2  =  newgamma2(newgamma2>0);
                        if(isempty(newgamma2)~=1  )
                            if((P_A*(X_W_I-R*T*i*newgamma2(1)*t_A/(P_A*D_HW*2*F))/P_SAT)>=1.)
                                newgamma   =     newgamma2(1);
                                gamma=newgamma;
                                k=((13.373+4.*((P_A*(X_W_I-R*T*i*gamma*t_A/(P_A*D_HW*2*F))/...
                                    P_SAT)-1.))-11.*gamma/Cdrag);
                            else
                                fprintf("aW0  larger  than unity was   not  found for after ")
                                fprintf("flooding  point region.");
                            end
                        end
                    end
                end
                funASR  =  @(z)  (1./((0.5193*(11.*double(subs(gamma))/Cdrag+...
                    double(subs(k))*exp(double(subs(i))*Cdrag*MW_N*z/...
                    (22*F*rhod_N*D_WN)))-0.326)*  exp(1268*(1./303.-1./T))));
                etta_ohmic(j)=double(subs(i)).*integral(funASR,0.,  t_M);
                X_H_II(j)=X_H_I-i*R*T*t_A/(2*F*P_A*D_HW);
                X_W_II(j)=X_W_I-gamma*i*R*T*t_A/(2*F*P_A*D_HW);
                X_O_III(j)=X_O_IV-i*R*T*t_C/(4*F*P_C*D_OW);
                X_W_III(j)=X_W_IV+(gamma+1)*i*R*T*t_C/(2*F*P_C*D_OW);
                %   Calculating the concentration loss  (only cathode)
                etta_conc(j)=(R*T/(2.*F))*(0.5*log(X_O_IV/(X_O_IV-R*T*i*t_C/...
                    (P_C*D_OW*4*F)))+log((X_W_IV+R*T*i*(gamma+1)*t_C/...
                    (2*F*P_C*D_OW))/X_W_IV));
                %   Calculating the concentration loss  (only cathode)
                V_cell(j)=Erev-etta_act(j)-etta_ohmic(j)-etta_conc(j);
                PowerD(j)=V_cell(j).*CD(j);
 
            end

            delete(wb); 
            
            cd_max=max(CD);
            pow_max=max(PowerD);
            app.MaxcurrentdensityEditField.Value=cd_max;
            app.MaxpowerdensityEditField.Value=pow_max;
            yyaxis(app.UIAxes,'left')
            plot(app.UIAxes,CD,V_cell,'DisplayName', sprintf('Voltage, P=%.1f,eT=%g,mT=%g',P,ETh,MTh))
            xlim(app.UIAxes,[0, max(CD+1000)])
            xlabel (app.UIAxes,'Current Density (A/m^2)')
            ylabel (app.UIAxes,'Voltage  (V)')
            legend(app.UIAxes,'Location','North');
            yyaxis(app.UIAxes,'right')
            plot(app.UIAxes,CD,PowerD,'DisplayName', sprintf('Power'))
            xlim(app.UIAxes,[0, max(CD+1000)])
            xlabel (app.UIAxes,'Current Density (A/m^2)')
            ylabel (app.UIAxes,'Power  (W/m^2)')
            legend(app.UIAxes,'Location','North');
            grid(app.UIAxes, 'on')
            
            figure(1)
            hold all;
            yyaxis left;
            hLine1=plot(CD,V_cell,'DisplayName', sprintf('Voltage, P=%.1f,eT=%g,mT=%g',P,ETh,MTh));
            xlabel ('Current Density (A/m^2)');
            ylabel ('Voltage  (V)');
            yyaxis right;
            hLine2=plot(CD,PowerD,'DisplayName', sprintf('Power'));
            xlabel ('Current Density (A/m^2)');
            ylabel ('Power  (W)');
            grid on;
            legend show;
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
        
        end

        % Callback function
        function OpenFigureButtonPushed(app, event)
            global P;
            global ETh;
            global MTh;
            global V_cell;
            global CD;
            global PowerD;
            figure(1)
            hold all;
            yyaxis left;
            hLine1=plot(CD,V_cell,'DisplayName', sprintf('Voltage, P=%.1f,eT=%g,mT=%g',P,ETh,MTh));
            xlabel ('Current Density (A/m^2)');
            ylabel ('Voltage  (V)');
            yyaxis right;
            hLine2=plot(CD,PowerD,'DisplayName', sprintf('Power'));
            xlabel ('Current Density (A/m^2)');
            ylabel ('Power  (W)');
            grid on;
            legend show;
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            winopen('Readme.pdf')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Polarization Curve/Power Curve')
            xlabel(app.UIAxes, 'Current Density, (Acm-2)')
            ylabel(app.UIAxes, 'Voltage (V)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.mLabel_2 = uilabel(app.UIFigure);
            app.mLabel_2.Text = 'μm';
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = 'μm';
            app.atmLabel = uilabel(app.UIFigure);
            app.atmLabel.Text = 'atm';
            app.OperationPressureEditField = uieditfield(app.UIFigure, 'numeric');
            app.OperationPressureEditField.Value = 1.2;
            app.OperationPressureEditFieldLabel = uilabel(app.UIFigure);
            app.OperationPressureEditFieldLabel.Text = 'Operation Pressure';
            app.ElectrodethicknessEditField = uieditfield(app.UIFigure, 'numeric');
            app.ElectrodethicknessEditField.Value = 450;
            app.ElectrodethicknessEditFieldLabel = uilabel(app.UIFigure);
            app.ElectrodethicknessEditFieldLabel.Text = 'Electrode thickness';
            app.MembranethicknessEditField = uieditfield(app.UIFigure, 'numeric');
            app.MembranethicknessEditField.Value = 125;
            app.MembranethicknessEditFieldLabel = uilabel(app.UIFigure);
            app.MembranethicknessEditFieldLabel.Text = 'Membrane thickness';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Reversible voltage of the cell'; 'Current density of the cell'; 'Anode thickness'; 'Cathode thickness'; 'Electrolyte thickness'; 'Temperature'; 'Saturation pressure of vapor'; 'Inlet mole fraction of hydrogen'; 'Inlet mole fraction of oxygen'; 'Inlet mole fraction of water vapor (cathode side)'; 'Pressure at cathode'; 'Pressure at anode'; 'Effective hydrogen (or water) diffusivity'; 'Effective oxygen (or water) diffusivity'; 'Water diffusivity in Nafion'; 'Transfer coefficient'; 'Exchange current density'; ''};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'1.0 V'; '0.5 Acm-2'; '450 µm'; '450 µm'; '125 µm'; '343 K'; '0.307 atm'; '0.9'; '0.19'; '0.1'; '1.2 atm'; '1.2 atm'; '1.49x10-5 m2s-1'; '2.95x10-6 m2s-1'; '3.81x10-10 m2s-1'; '2'; '0.0001 Acm-2'; ''};
            app.PhysicalpropertiesLabel = uilabel(app.UIFigure);
            app.PhysicalpropertiesLabel.Text = 'Physical properties';
            app.ValuesLabel = uilabel(app.UIFigure);
            app.ValuesLabel.Text = 'Values';
            app.Tableoftheexample211pag199CodefromAppendixBpag443Label = uilabel(app.UIFigure);
            app.Tableoftheexample211pag199CodefromAppendixBpag443Label.Text = 'Table of the example 2.11, pag. 199. Code from Appendix B, pag. 443.  ';
            app.GeneratecurvesButton = uibutton(app.UIFigure, 'push');
            app.GeneratecurvesButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratecurvesButtonPushed, true);
            app.GeneratecurvesButton.Text = 'Generate curves';
            app.ChangevaluesLabel = uilabel(app.UIFigure);
            app.ChangevaluesLabel.Text = 'Change values';
            app.Wm2Label = uilabel(app.UIFigure);
            app.Wm2Label.Text = 'Wm-2';
            app.MaxpowerdensityEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxpowerdensityEditField.ValueDisplayFormat = '%.2f';
            app.MaxpowerdensityEditFieldLabel = uilabel(app.UIFigure);
            app.MaxpowerdensityEditFieldLabel.Text = 'Max power density';
            app.Am2Label = uilabel(app.UIFigure);
            app.Am2Label.Text = 'Am-2';
            app.MaxcurrentdensityEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxcurrentdensityEditField.ValueDisplayFormat = '%.2f';
            app.MaxcurrentdensityEditFieldLabel = uilabel(app.UIFigure);
            app.MaxcurrentdensityEditFieldLabel.Text = 'Max current density';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Icon = 'help_icon.png';
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mypemfc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end